classdef droplet_detection_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        w_lengthEditFieldLabel  matlab.ui.control.Label
        w_lengthEditField       matlab.ui.control.NumericEditField
        w_heightEditFieldLabel  matlab.ui.control.Label
        w_heightEditField       matlab.ui.control.NumericEditField
        w_origEditFieldLabel    matlab.ui.control.Label
        w_origEditField         matlab.ui.control.NumericEditField
        threshEditFieldLabel    matlab.ui.control.Label
        threshEditField         matlab.ui.control.NumericEditField
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes2_2               matlab.ui.control.UIAxes
        FrameEditFieldLabel     matlab.ui.control.Label
        FrameEditField          matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        Slider_2                matlab.ui.control.Slider
    end


    properties (Access = private)
        frame
        image
        CineFileLocation
        CineFileName
        info
        LinLUT
        wl
        wh
        wo
        img
        % Description
    end

    methods (Access = private)
    
        function populate(app)
            app.img = cineRead2(app.CineFileLocation,app.CineFileName,app.frame,app.info,app.LinLUT,app.wh,app.wl,app.wo);
            imagesc(app.img,'Parent',app.UIAxes2)
            plot(app.img,'Parent',app.UIAxes2_2)
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CineFileLocation = evalin('base', 'cine_folder');
            app.CineFileName = evalin('base', 'cine_file');
            app.info = evalin('base', 'info');
            app.LinLUT = evalin('base', 'LinLUT');
            app.wl = 1280;
            app.wh = 1;
            app.wo = 0;
            
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            app.frame = app.FrameEditField.Value;
            
            app.image = cineRead(app.CineFileLocation,app.CineFileName,app.frame,app.info,app.LinLUT);
            app.image = imresize(app.image,3,'bicubic');
            app.image = imadjust(app.image);
            imshow(app.image,'Parent',app.UIAxes)

        end

        % Value changed function: w_lengthEditField
        function w_lengthEditFieldValueChanged(app, event)
            app.wl = app.w_lengthEditField.Value;
            populate(app)
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.wh = floor(event.Value);
            populate(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.w_lengthEditFieldLabel = uilabel(app.UIFigure);
            app.w_lengthEditFieldLabel.Text = 'w_length';
            app.w_lengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.w_lengthEditField.ValueChangedFcn = createCallbackFcn(app, @w_lengthEditFieldValueChanged, true);
            app.w_heightEditFieldLabel = uilabel(app.UIFigure);
            app.w_heightEditFieldLabel.Text = 'w_height';
            app.w_heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.w_origEditFieldLabel = uilabel(app.UIFigure);
            app.w_origEditFieldLabel.Text = 'w_orig';
            app.w_origEditField = uieditfield(app.UIFigure, 'numeric');
            app.threshEditFieldLabel = uilabel(app.UIFigure);
            app.threshEditFieldLabel.Text = 'thresh';
            app.threshEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 16];
            app.Slider_2.MajorTickLabels = {'1', '', '', '', '', '', '16'};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 16;
        end
    end

    methods (Access = public)

        % Construct app
        function app = droplet_detection_ui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end